classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SeleccinTab                     matlab.ui.container.Tab
        CargararchivoButton             matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        AudioLabel                      matlab.ui.control.Label
        WaveLabel                       matlab.ui.control.Label
        ParmetrosdeconfiguracindefiltrosporcanalPanel  matlab.ui.container.Panel
        Parmetrosdelfiltroparacanal1Label  matlab.ui.control.Label
        Parmetrosdelfiltroparacanal2Label  matlab.ui.control.Label
        FactordecalidadEditFieldLabel   matlab.ui.control.Label
        FactordecalidadEditField        matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortebajaEditField_2  matlab.ui.control.NumericEditField
        FactordecalidadEditField_2Label  matlab.ui.control.Label
        FactordecalidadEditField_2      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortealtaEditField_2  matlab.ui.control.NumericEditField
        ExportarPanel                   matlab.ui.container.Panel
        wavLabel                        matlab.ui.control.Label
        NombredearchivoEditField        matlab.ui.control.EditField
        NombredearchivoEditFieldLabel   matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ExportarButton                  matlab.ui.control.Button
        SeleccindetipodefiltroPanel     matlab.ui.container.Panel
        SeleccindefiltroLabel           matlab.ui.control.Label
        AplicarparmetrosButton          matlab.ui.control.Button
        AplicarparmetrosButton_2        matlab.ui.control.Button
        TipodefiltrodelCanal1DropDown   matlab.ui.control.DropDown
        TipodefiltrodelCanal1DropDownLabel  matlab.ui.control.Label
        TipodefiltrodelCanal2DropDownLabel  matlab.ui.control.Label
        TipodefiltrodelCanal2DropDown   matlab.ui.control.DropDown
        ReproduccindeaudiofiltradoPanel  matlab.ui.container.Panel
        ReproducirCanal1Button          matlab.ui.control.Button
        ReproducirCanal2Button          matlab.ui.control.Button
        ReproduciramboscanalesButton    matlab.ui.control.Button
        PausaButton                     matlab.ui.control.Button
        ReproduccinddeaudiooriginalPanel  matlab.ui.container.Panel
        ReproducirButton                matlab.ui.control.Button
        PausaButton_2                   matlab.ui.control.Button
        ResumirButton                   matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        EfectosTab                      matlab.ui.container.Tab
        SELECCINSwitchLabel             matlab.ui.control.Label
        SELECCINSwitch                  matlab.ui.control.Switch
        EfectosCanal2Panel              matlab.ui.container.Panel
        VibratoPanel_2                  matlab.ui.container.Panel
        RATEHzKnob_4Label               matlab.ui.control.Label
        RATEHzKnob_4                    matlab.ui.control.Knob
        MODFREQKnob_2Label              matlab.ui.control.Label
        MODFREQKnob_2                   matlab.ui.control.Knob
        VibratoCheckBox_2               matlab.ui.control.CheckBox
        WIDTHKnob_2Label                matlab.ui.control.Label
        WIDTHKnob_2                     matlab.ui.control.Knob
        PhaseoFlangerPanel_2            matlab.ui.container.Panel
        RATEHzKnob_5Label_2             matlab.ui.control.Label
        RATEHzKnob_5                    matlab.ui.control.Knob
        DELAYmsKnob_4Label              matlab.ui.control.Label
        DELAYmsKnob_4                   matlab.ui.control.Knob
        PhaseroFlangerCheckBox_2        matlab.ui.control.CheckBox
        ReverberacinPanel_2             matlab.ui.container.Panel
        ReverberacinCheckBox_2          matlab.ui.control.CheckBox
        RATEHzKnob_6Label_2             matlab.ui.control.Label
        RATEHzKnob_6                    matlab.ui.control.Knob
        DELAYmsKnob_5Label_2            matlab.ui.control.Label
        DELAYmsKnob_5                   matlab.ui.control.Knob
        DistorsinPanel_2                matlab.ui.container.Panel
        GAINKnob_2Label                 matlab.ui.control.Label
        GAINKnob_2                      matlab.ui.control.Knob
        MIXKnob_2Label                  matlab.ui.control.Label
        MIXKnob_2                       matlab.ui.control.Knob
        DistorsinCheckBox_2             matlab.ui.control.CheckBox
        EcoPanel_2                      matlab.ui.container.Panel
        ATENUACINKnob_2Label            matlab.ui.control.Label
        ATENUACINKnob_2                 matlab.ui.control.Knob
        DELAYmsKnob_6Label_2            matlab.ui.control.Label
        DELAYmsKnob_6                   matlab.ui.control.Knob
        EcoCheckBox_2                   matlab.ui.control.CheckBox
        EfectosCanal1Panel              matlab.ui.container.Panel
        VibratoPanel_1                  matlab.ui.container.Panel
        RATEHzKnob_7Label               matlab.ui.control.Label
        RATEHzKnob_1                    matlab.ui.control.Knob
        MODFREQKnob_3Label              matlab.ui.control.Label
        MODFREQKnob_1                   matlab.ui.control.Knob
        VibratoCheckBox_1               matlab.ui.control.CheckBox
        WIDTHKnob_3Label                matlab.ui.control.Label
        WIDTHKnob_1                     matlab.ui.control.Knob
        PhaseoFlangerPanel_1            matlab.ui.container.Panel
        RATEHzKnob_8Label               matlab.ui.control.Label
        RATEHzKnob_2                    matlab.ui.control.Knob
        DELAYmsKnob_7Label              matlab.ui.control.Label
        DELAYmsKnob_2                   matlab.ui.control.Knob
        PhaseroFlangerCheckBox_1        matlab.ui.control.CheckBox
        ReverberacinPanel_1             matlab.ui.container.Panel
        ReverberacinCheckBox_1          matlab.ui.control.CheckBox
        RATEHzKnob_9Label               matlab.ui.control.Label
        RATEHzKnob_3                    matlab.ui.control.Knob
        DELAYmsKnob_8Label              matlab.ui.control.Label
        DELAYmsKnob_1                   matlab.ui.control.Knob
        DistorsinPanel_1                matlab.ui.container.Panel
        GAINKnob_3Label                 matlab.ui.control.Label
        GAINKnob_1                      matlab.ui.control.Knob
        MIXKnob_3Label                  matlab.ui.control.Label
        MIXKnob_1                       matlab.ui.control.Knob
        DistorsinCheckBox_1             matlab.ui.control.CheckBox
        EcoPanel_1                      matlab.ui.container.Panel
        ATENUACINKnob_3Label            matlab.ui.control.Label
        ATENUACINKnob_1                 matlab.ui.control.Knob
        DELAYmsKnob_9Label              matlab.ui.control.Label
        DELAYmsKnob_3                   matlab.ui.control.Knob
        EcoCheckBox_1                   matlab.ui.control.CheckBox
        AplicarEfectosCanal2Button      matlab.ui.control.Button
        AplicarEfectosCanal1Button      matlab.ui.control.Button
        GraficosTab                     matlab.ui.container.Tab
        GraficarCanal1Button            matlab.ui.control.Button
        GraficarCanal2Button            matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
    end

    properties(Access=public)
        file2
        player
        player2
        player3
        player4
        salfir_x
        saliir_x
        archivo
        editado_can1
        editado_can2
        salfir2_x
        saliir2_x
        sal_x2
        sal_x
        total_x
    end
    methods (Access = private)


        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargararchivoButton
        function CargarArchivo(app, event)

            global file;
            global x; 
            global x_out;
            global Fs; 
            global len;
            global canales;
            
            app.ReverberacinCheckBox_1.Value = false;
            app.VibratoCheckBox_1.Value = false;
            app.PhaseroFlangerCheckBox_1.Value = false;
      
            [file, path] = uigetfile({'*.wav;*.mp3', 'Audio Files'});   % selecciona un archivo con extensiones específicas
            fullFileName = fullfile(path, file);
            
            if  file == 0
                app.Label.Text = 'Archivo no seleccionado';
                app.Lamp_2.Color = 'r';
            else
                app.Label.Text = file;
                [x, Fs] = audioread(fullFileName);  % obtiene los datos de un archivo *.wav
                app.archivo = file;
                [len, canales] = size(x)
                x_out = zeros(size(x));
                app.player = audioplayer(x,Fs);
                app.Lamp_2.Color = 'g';
                if canales == 1
                    app.SELECCINSwitch.Visible = 'off';
                    app.SELECCINSwitchLabel.Visible = 'off';
                    app.GraficarCanal2Button.Visible = 'off';
                else
                    app.SELECCINSwitch.Visible = 'on';
                    app.SELECCINSwitchLabel.Visible = 'on';
                    app.GraficarCanal2Button.Visible = 'on';
                end
            end
            
        end

        % Button pushed function: ExportarButton
        function Exportar(app, event)
            
            global Fs;
            
            out_filename = app.NombredearchivoEditField.Value; % guarda nombre ingresado en la campo de edición
            out_filename = strcat(out_filename, ".wav")
            [file, path] = uiputfile(out_filename); % obtiene la dirección y el nombre para guardar el archivo
            
            % en caso de que se cancele "guardar archivo"
            if file == 0   
                return;
            end
            
            fullFileName = fullfile(path, file);    % construye la ubicación del archivo
            audiowrite(fullFileName, app.total_x, Fs)         % guarda el archivo en un formato *.wav
            
        end

        % Value changed function: EcoCheckBox_1
        function toggle_eco_effect(app, event)
            
            global eco_ch1;
            
            value = app.EcoCheckBox_1.Value;
            
            if value
                eco_ch1 = 1;
            else
                eco_ch1 = 0;   
            end 

        end

        % Value changed function: ReverberacinCheckBox_1
        function toggle_reverberation_effect(app, event)
            
            global reverb_ch1;
            
            value = app.ReverberacinCheckBox_1.Value;
            
            if value
                reverb_ch1 = 1;
            else
                reverb_ch1 = 0;
            end

        end

        % Value changed function: VibratoCheckBox_1
        function toggle_vibrato_effect(app, event)
            
            global vibrato_ch1;

            value = app.VibratoCheckBox_1.Value;
            
            if value 
                vibrato_ch1 = 1;
            else
                vibrato_ch1 = 0;
            end

        end

        % Value changed function: PhaseroFlangerCheckBox_1
        function toggle_phaser_effect(app, event)
            
            global phaser_ch1;
            
            value = app.PhaseroFlangerCheckBox_1.Value;
            
            if value
                phaser_ch1 = 1;
            else
                phaser_ch1 = 0;
            end
            
        end

        % Value changed function: DistorsinCheckBox_1
        function toggle_distortion_effect(app, event)
            
            global distortion_ch1;
            
            value = app.DistorsinCheckBox_1.Value;
            
            if value
                distortion_ch1 = 1;
            else
                distortion_ch1 = 0;
            end
 
        end

        % Button pushed function: PausaButton_2
        function PausaButton_2Pushed(app, event)
            pause(app.player);%pausa la reproduccion de audio
            app.Lamp_2.Color = 'r';
        end

        % Button pushed function: ResumirButton
        function ResumirButtonPushed(app, event)
            resume(app.player);%resume la reproduccion del audio donde se quedo
            app.Lamp_2.Color = 'g';
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop(app.player);%da fin a la reproduccion del audio
            app.Lamp_2.Color = 'r';
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            play(app.player);%reproduce el audio
        end

        % Callback function
        function play_audio_effect(app, event)
            
            global Fs;
            global x_out;

            soundsc(x_out, Fs);
            
        end

        % Value changed function: SELECCINSwitch
        function channel_select(app, event)
            value = app.SELECCINSwitch.Value;
            switch value
                case 'Canal 1'
                    app.EfectosCanal1Panel.Visible = 'on';
                    app.EfectosCanal2Panel.Visible = 'off';
                    app.AplicarEfectosCanal1Button.Visible = 'on';
                    app.AplicarEfectosCanal2Button.Visible = 'off';
                case 'Canal 2'
                    app.EfectosCanal1Panel.Visible = 'off';
                    app.EfectosCanal2Panel.Visible = 'on';
                    app.AplicarEfectosCanal1Button.Visible = 'off';
                    app.AplicarEfectosCanal2Button.Visible = 'on';
            end 
        end

        % Button pushed function: AplicarEfectosCanal1Button
        function aplicar_efectos_ch1(app, event)

            global eco_ch1;
            global reverb_ch1;
            global vibrato_ch1;
            global phaser_ch1;
            global distortion_ch1;
            global x_out_ch1;   % señal de salida (a la que se le aplicaran efectos)
            
            global Fs;
            
            ch1 = app.sal_x;
            x_out_ch1 = zeros(size(ch1));

            if eco_ch1
                in_eco = ch1;
                delay1 = app.DELAYmsKnob_3.Value;
                delay2 = delay1 + 0.1;
                
                a1 = app.ATENUACINKnob_1.Value;
                a2 = a1 - 0.1;
                
                R1 = round(delay1*Fs);     % retardo 1
                R2 = round(delay2*Fs);     % retardo 2
                
                eco_out = zeros(size(in_eco));
                for n = R2+1:1:length(in_eco)
                    eco_out(n) = in_eco(n) + a1*in_eco(n-R1) + a2*eco_out(n-R2);
                end
                disp('Eco aplicado')
            end
                
            if reverb_ch1
                if eco_ch1
                    in_reverb = eco_out;
                else
                    in_reverb = ch1;
                end
                
                Delay = app.DELAYmsKnob_1.Value;
                Rate = app.RATEHzKnob_3.Value;
                
                R1 = round(Delay*Rate);  % retardo 1
                R2 = round(2*R1);      % retardo 2
                R3 = round(0.5*R1);    % retardo 3
                
                a1 = 0.2;   a2 = 0.2;   % atenuaciones
                
                reverb_out = zeros(size(in_reverb));
                for n = R2+R3+1:1:length(x)
                    reverb_out(n) = in_reverb(n) + in_reverb(n-R2) + a2*in_reverb(n-R2-R3) + a1*reverb_out(n-R1);
                end
                disp('Reververación aplicada')

            end
            
            if vibrato_ch1
                if reverb_ch1 
                    in_vibrato = reverb_out;
                elseif eco_ch1
                    in_vibrato = eco_out;
                else 
                    in_vibrato = ch1;
                end
                
                Rate = app.RATEHzKnob_1.Value;
                Width = app.WIDTHKnob_1.Value;
                Modfreq = app.MODFREQKnob_1.Value;
                
                Delay = Width; % delay básico en segundos (0 - 0.01 segundos)
                DelayM = round(Delay*Rate); % delay básico en # de muestras
                AnchoM = round(Width*Rate); % ancho de modulación en # de muestras
                
                if AnchoM > DelayM 
                  error('delay greater than basic delay !!!');
                  return;
                end 
                
                ModfreqM = Modfreq/Rate; % frecuencia de modulación en # de muestras
                N = length(in_vibrato);        % # of samples in WAV-file
                Delay_length = 2 + DelayM + AnchoM * 2;    % length of the entire delay  
                Delayline = zeros(Delay_length,1); % memory allocation for delay
                vibrato_out = zeros(size(in_vibrato));     % memory allocation for output vector
                
                for n=1:(N-1)
                   M = ModfreqM;
                   MOD = sin(M*2*pi*n);
                   TAP = 1 + DelayM + AnchoM*MOD;
                   i = floor(TAP);
                   frac = TAP - i;
                   Delayline=[in_vibrato(n); Delayline(1:Delay_length-1)]; 
                   vibrato_out(n) = Delayline(i+1)*frac + Delayline(i)*(1-frac);
                end  
                disp('Vibrato aplicado')
            end
            
            if phaser_ch1
                if vibrato_ch1
                    in_phaser = vibrato_out;
                elseif reverb_ch1 
                    in_phaser = reverb_out;
                elseif eco_ch1
                    in_phaser = eco_out;
                else
                    in_phaser = ch1;
                end
                
                max_delay = app.DELAYmsKnob_2.Value;
                rate = app.RATEHzKnob_2.Value;
                
                osc_sin_ref = (sin(2*pi*(1:length(in_phaser))*(rate/Fs)));
                max_samp_delay = round(max_delay*Fs);

                phaser_out(1:max_samp_delay)=in_phaser(1:max_samp_delay);
                
                amp = 0.7;
                
                for n = (max_samp_delay+1):length(in_phaser)
                    sin_val = abs(osc_sin_ref(n));
                    delay = ceil(sin_val*max_samp_delay);
                    phaser_out(n) = (amp*in_phaser(n)) + amp*(in_phaser(n-delay));
                end
                disp('Phaser aplicado')
            end
            
            if distortion_ch1
                if phaser_ch1
                    in_distortion = phaser_out;
                elseif vibrato_ch1
                    in_distortion = vibrato_out;
                elseif reverb_ch1 
                    in_distortion = reverb_out;
                elseif eco_ch1
                    in_distortion = eco_out;
                else
                    in_distortion = ch1;
                end
                
                gain = app.GAINKnob_1.Value;
                mix = app.MIXKnob_1.Value;
                
                distortion_out = zeros(size(in_distortion));
                
                q=in_distortion*gain/max(abs(in_distortion));
                z=sign(-q).*(1-exp(sign(-q).*q));
                distortion_out = mix*z*max(abs(in_distortion))/max(abs(z))+(1-mix)*in_distortion;
                distortion_out = distortion_out.*max(abs(in_distortion))/max(abs(distortion_out));
                disp('Distorsión aplicada')
            end
            
            if distortion_ch1
                x_out_ch1 = distortion_out;
            elseif phaser_ch1
                x_out_ch1 = phaser_out';
            elseif vibrato_ch1
                x_out_ch1 = vibrato_out';
            elseif reverb_ch1
                x_out_ch1 = reverb_out';
            elseif eco_ch1
                x_out_ch1 = eco_out';
            else
                x_out_ch1 = ch1;
            end
                  
        end

        % Value changed function: VibratoCheckBox_2
        function toggle_vibrato_effect_ch2(app, event)
            
            global vibrato_ch2;

            value = app.VibratoCheckBox_2.Value;
            
            if value 
                vibrato_ch2 = 1;
            else
                vibrato_ch2 = 0;
            end
            
        end

        % Value changed function: PhaseroFlangerCheckBox_2
        function toggle_phaser_effect_ch2(app, event)
            
            global phaser_ch2;
            
            value = app.PhaseroFlangerCheckBox_2.Value;
            
            if value
                phaser_ch2 = 1;
            else
                phaser_ch2 = 0;
            end
        end

        % Value changed function: ReverberacinCheckBox_2
        function toggle_reverberation_effect_ch2(app, event)
            
            global reverb_ch2;
            
            value = app.ReverberacinCheckBox_2.Value;
            
            if value
                reverb_ch2 = 1;
            else
                reverb_ch2 = 0;
            end
        end

        % Value changed function: DistorsinCheckBox_2
        function toggle_distortion_effect_ch2(app, event)
            
            global distortion_ch2;
            
            value = app.DistorsinCheckBox_2.Value;
            
            if value
                distortion_ch2 = 1;
            else
                distortion_ch2 = 0;
            end
        end

        % Value changed function: EcoCheckBox_2
        function toggle_eco_effect_ch2(app, event)
            
            global eco_ch2;
            
            value = app.EcoCheckBox_2.Value;
            
            if value
                eco_ch2 = 1;
            else
                eco_ch2 = 0;   
            end 
        end

        % Button pushed function: AplicarEfectosCanal2Button
        function aplicar_efectos_ch2(app, event)

            global eco_ch2;
            global reverb_ch2;
            global vibrato_ch2;
            global phaser_ch2;
            global distortion_ch2;
            global x_out_ch2; 

            
            global Fs;

            ch2 = app.sal_x2;
            x_out_ch2 = zeros(size(ch2));

            if eco_ch2
                in_eco = ch2;
                delay1 = app.DELAYmsKnob_6.Value;
                delay2 = delay1 + 0.1;
                
                a1 = app.ATENUACINKnob_2.Value;
                a2 = a1 - 0.1;
                
                R1 = round(delay1*Fs);     % retardo 1
                R2 = round(delay2*Fs);     % retardo 2
                
                eco_out = zeros(size(in_eco));
                for n = R2+1:1:length(in_eco)
                    eco_out(n) = in_eco(n) + a1*in_eco(n-R1) + a2*eco_out(n-R2);
                end
                disp('Eco aplicado')
            end
                
            if reverb_ch2
                if eco_ch2
                    in_reverb = eco_out;
                else
                    in_reverb = ch2;
                end
                
                Delay = app.DELAYmsKnob_5.Value;
                Rate = app.RATEHzKnob_6.Value;
                
                R1 = round(Delay*Rate);  % retardo 1
                R2 = round(2*R1);      % retardo 2
                R3 = round(0.5*R1);    % retardo 3
                
                a1 = 0.2;   a2 = 0.2;   % atenuaciones
                
                reverb_out = zeros(size(in_reverb));
                for n = R2+R3+1:1:length(x)
                    reverb_out(n) = in_reverb(n) + in_reverb(n-R2) + a2*in_reverb(n-R2-R3) + a1*reverb_out(n-R1);
                end
                disp('Reververación aplicada')

            end
            
            if vibrato_ch2
                if reverb_ch2 
                    in_vibrato = reverb_out;
                elseif eco_ch2
                    in_vibrato = eco_out;
                else 
                    in_vibrato = ch2;
                end
                
                Rate = app.RATEHzKnob_4.Value;
                Width = app.WIDTHKnob_2.Value;
                Modfreq = app.MODFREQKnob_2.Value;
                
                Delay = Width; % delay básico en segundos (0 - 0.01 segundos)
                DelayM = round(Delay*Rate); % delay básico en # de muestras
                AnchoM = round(Width*Rate); % ancho de modulación en # de muestras
                
                if AnchoM > DelayM 
                  error('delay greater than basic delay !!!');
                  return;
                end 
                
                ModfreqM = Modfreq/Rate; % frecuencia de modulación en # de muestras
                N = length(in_vibrato);        % # of samples in WAV-file
                Delay_length = 2 + DelayM + AnchoM * 2;    % length of the entire delay  
                Delayline = zeros(Delay_length,1); % memory allocation for delay
                vibrato_out = zeros(size(in_vibrato));     % memory allocation for output vector
                
                for n=1:(N-1)
                   M = ModfreqM;
                   MOD = sin(M*2*pi*n);
                   TAP = 1 + DelayM + AnchoM*MOD;
                   i = floor(TAP);
                   frac = TAP - i;
                   Delayline=[in_vibrato(n); Delayline(1:Delay_length-1)]; 
                   vibrato_out(n) = Delayline(i+1)*frac + Delayline(i)*(1-frac);
                end  
                disp('Vibrato aplicado')
            end
            
            if phaser_ch2
                if vibrato_ch2
                    in_phaser = vibrato_out;
                elseif reverb_ch2
                    in_phaser = reverb_out;
                elseif eco_ch2
                    in_phaser = eco_out;
                else
                    in_phaser = ch2;
                end
                
                max_delay = app.DELAYmsKnob_4.Value;
                rate = app.RATEHzKnob_5.Value;
                
                osc_sin_ref = (sin(2*pi*(1:length(in_phaser))*(rate/Fs)));
                max_samp_delay = round(max_delay*Fs);

                phaser_out(1:max_samp_delay)=in_phaser(1:max_samp_delay);
                
                amp = 0.7;
                
                for n = (max_samp_delay+1):length(in_phaser)
                    sin_val = abs(osc_sin_ref(n));
                    delay = ceil(sin_val*max_samp_delay);
                    phaser_out(n) = (amp*in_phaser(n)) + amp*(in_phaser(n-delay));
                end
                disp('Phaser aplicado')
            end
            
            if distortion_ch2
                if phaser_ch2
                    in_distortion = phaser_out;
                elseif vibrato_ch2
                    in_distortion = vibrato_out;
                elseif reverb_ch2 
                    in_distortion = reverb_out;
                elseif eco_ch2
                    in_distortion = eco_out;
                else
                    in_distortion = ch2;
                end
                
                gain = app.GAINKnob_2.Value;
                mix = app.MIXKnob_2.Value;
                
                distortion_out = zeros(size(in_distortion));
                
                q=in_distortion*gain/max(abs(in_distortion));
                z=sign(-q).*(1-exp(sign(-q).*q));
                distortion_out = mix*z*max(abs(in_distortion))/max(abs(z))+(1-mix)*in_distortion;
                distortion_out = distortion_out.*max(abs(in_distortion))/max(abs(distortion_out));
                disp('Distorsión aplicada')
            end
            
            if distortion_ch2
                x_out_ch2 = distortion_out;
            elseif phaser_ch2
                x_out_ch2 = phaser_out';
            elseif vibrato_ch2
                x_out_ch2 = vibrato_out';
            elseif reverb_ch2
                x_out_ch2 = reverb_out';
            elseif eco_ch2
                x_out_ch2 = eco_out';
            else
                x_out_ch2 = ch2;
            end
        end

        % Button pushed function: GraficarCanal1Button
        function graficar_ch1(app, event)

            global x;
            global Fs;
            global h1;
            global w1;
            global h1fir;
            global w1fir;
            global len;
            global P1_CH1;
            global P1_CH1_PROCESADO;
            global phasedeg_iirch1;
            global phasedeg_firch1;
            
            ch1 = x(:,1);
            
            X = fft(ch1);
            P2_1 = abs(X/len);
            P1_CH1 = P2_1(1:len/2+1);
            P1_CH1(2:end-1) = 2*P1_CH1(2:end-1);
            
            f = Fs*(0:(len/2))/len;
            
            plot(app.UIAxes1, f, P1_CH1, 'b');
            title(app.UIAxes1, "Espectro Unilateral de audio de entrada - Canal 1");
            xlabel(app.UIAxes1, "Hz");
            ylabel(app.UIAxes1, "|P1_CH1(f)|")
            grid(app.UIAxes1, 'on')
            

             ch1_procesado = app.sal_x;
             
             X1 = fft(ch1_procesado);
             P2_1P = abs(X1/len);
             P1_CH1_PROCESADO = P2_1P(1:len/2+1);
             P1_CH1_PROCESADO(2:end-1) = 2*P1_CH1_PROCESADO(2:end-1);
             
             
             plot(app.UIAxes2, f, P1_CH1_PROCESADO, 'b');
             title(app.UIAxes2, "Espectro Unilateral de audio de entrada procesada - Canal 1");
             xlabel(app.UIAxes2, "Hz");
             ylabel(app.UIAxes2, "|P1_CH1(f)|")
             grid(app.UIAxes2, 'on')
             
             value = app.TipodefiltrodelCanal1DropDown.Value;
             
             if strcmp(value,'IIR')
                 plot(app.UIAxes3, w1/pi, 20*log10(abs(h1)),'b');
                 title(app.UIAxes3,'Magnitud vs Frecuencia angular');
                 xlabel(app.UIAxes3,'Frecuencia angular normalizada');
                 ylabel(app.UIAxes3,'Magnitud')
                 
                 plot(app.UIAxes4, w1/pi, phasedeg_iirch1,'b');
                 title(app.UIAxes4,'Fase vs Frecuencia angular');
                 xlabel(app.UIAxes4,'Frecuencia angular normalizada');
                 ylabel(app.UIAxes4,'Fase (grados)')
             elseif strcmp(value,'FIR')
                 plot(app.UIAxes3, w1fir/pi, 20*log10(abs(h1fir)),'b');
                 title(app.UIAxes3,'Magnitud vs Frecuencia angular');
                 xlabel(app.UIAxes3,'Frecuencia angular normalizada');
                 ylabel(app.UIAxes3,'Magnitud')
                 
                 plot(app.UIAxes4, w1/pi, phasedeg_firch1,'b');
                 title(app.UIAxes4,'Fase vs Frecuencia angular');
                 xlabel(app.UIAxes4,'Frecuencia angular normalizada');
                 ylabel(app.UIAxes4,'Fase (grados)')
             else
                 cla(app.UIAxes3)
                 cla(app.UIAxes4)
             end
             
        end

        % Button pushed function: GraficarCanal2Button
        function graficar_ch2(app, event)

            global x;
            global len;
            global h2;
            global w2;
            global h2fir;
            global w2fir;
            global Fs;
            global P1_CH2;
            global P1_CH2_PROCESADO;
            global phasedeg_iirch2;
            global phasedeg_firch2;
            
            ch2 = x(:,2);
            
            f = Fs*(0:(len/2))/len;
            
            X = fft(ch2);
            P2_1 = abs(X/len);
            P1_CH2 = P2_1(1:len/2+1);
            P1_CH2(2:end-1) = 2*P1_CH2(2:end-1);
            
            plot(app.UIAxes1, f, P1_CH2, 'r');
            title(app.UIAxes1, "Espectro Unilateral de audio de entrada - Canal 2");
            xlabel(app.UIAxes1, "Hz");
            ylabel(app.UIAxes1, "|P1_CH2(f)|")
            grid(app.UIAxes1, 'on')
            
            ch2_procesado = app.sal_x2;

            X1 = fft(ch2_procesado);
            P2_1P = abs(X1/len);
            P1_CH2_PROCESADO = P2_1P(1:len/2+1);
            P1_CH2_PROCESADO(2:end-1) = 2*P1_CH2_PROCESADO(2:end-1);
            
            plot(app.UIAxes2, f, P1_CH2_PROCESADO, 'r');
            title(app.UIAxes2, "Espectro Unilateral de audio de entrada procesada - Canal 2");
            xlabel(app.UIAxes2, "Hz");
            ylabel(app.UIAxes2, "|P1_CH1(f)|")
            grid(app.UIAxes2, 'on')
            
            
            value = app.TipodefiltrodelCanal2DropDown.Value;
            if strcmp(value,'IIR')
                 plot(app.UIAxes3, w2/pi, 20*log10(abs(h2)),'r');
                 title(app.UIAxes3,'Magnitud vs Frecuencia angular');
                 xlabel(app.UIAxes3,'Frecuencia angular normalizada');
                 ylabel(app.UIAxes3,'Magnitud')
                 
                 plot(app.UIAxes4, w2/pi, phasedeg_iirch2,'r');
                title(app.UIAxes4,'Fase vs Frecuencia angular');
                xlabel(app.UIAxes4,'Frecuencia angular normalizada');
                ylabel(app.UIAxes4,'Fase (grados)')
             elseif strcmp(value,'FIR')
                 plot(app.UIAxes3, w2fir/pi, 20*log10(abs(h2fir)),'r');
                 title(app.UIAxes3,'Magnitud vs Frecuencia angular');
                 xlabel(app.UIAxes3,'Frecuencia angular normalizada');
                 ylabel(app.UIAxes3,'Magnitud')
                 
                 plot(app.UIAxes4, w2/pi, phasedeg_firch2,'r');
                title(app.UIAxes4,'Fase vs Frecuencia angular');
                xlabel(app.UIAxes4,'Frecuencia angular normalizada');
                ylabel(app.UIAxes4,'Fase (grados)')
             else
                 cla(app.UIAxes3)
                 cla(app.UIAxes4)
            end

        end

        % Button pushed function: ReproduciramboscanalesButton
        function ReproduciramboscanalesButtonPushed(app, event)
            global Fs;
            global x_out_ch1;
            global x_out_ch2;
            %de lado izquierdo de la matriz colocamos el canal 1 y de lado
            %derecho el canal 2d
            app.total_x = [x_out_ch1 x_out_ch2];
            %Reproductor final
            app.player2 = audioplayer(app.total_x,Fs);
            play(app.player2);
        end

        % Button pushed function: AplicarparmetrosButton
        function aplicar_param_ch1(app, event)
            global Hd;
            global fa;
            global fb;
            global Ts;
            global len;
            global gain;
            global a;
            global b;
            global h1;
            global w1;
            global h1fir;
            global w1fir;
            global phasedeg_iirch1;
            global phasedeg_firch1;
            global x;
            global Fs;
            gain= app.GainSlider.Value;
            can1=x(:,1);
            cal1=app.FactordecalidadEditField.Value;
            if cal1<=0
                cal1=0.2;
                app.FactordecalidadEditField.Value=0.2;
            else
                %no hacer nada
            end
            app.editado_can1 = 5*cal1*can1*gain;
            value = app.TipodefiltrodelCanal1DropDown.Value;
            %datos generales
            syms s z
            fa=app.FrecuenciadecortealtaEditField.Value;
            fb=app.FrecuenciadecortebajaEditField.Value;
            if fb>=100
                Fstop1fir=abs(fb-100); %primer frecuencia de paro
                Fstop2fir=abs(fa+100);% segunda frecuencia de paro
            elseif (fb<=0 && fa>0)
                Fstop1fir=0;
                Fpass1fir=0.01;
                Fpass2fir=fa;
                Fstop2fir=fa+100;
            elseif (fa<=0 && fb>0);
                Fstop1fir=0;
                Fpass1fir=fb;
                Fpass2fir=fb+1;
                Fstop2fir=Fpass2fir+100;
            elseif (fa==0 && fb ==0);
                Fstop1fir=0;
                Fpass1fir=100;
                Fpass2fir=13300;
                Fstop2fir=14300;
            else
                Fstop1fir=fb;
                Fpass1fir=fb+10;
                Fpass2fir=fa;
                Fstop2fir=fa+100;
            end
            Ts=1/Fs;
%             K_1 = tan((pi*fb)/Fs);
%             d_1 = ((K_1^2)*cal1)+K_1+cal1;
%             a0_1 = K_1/d_1;
%             a1_1 = 0;
%             a2_1 = -K_1/d_1;
%             b1_1 = (2*cal1*((K_1^2)-1))/d_1;
%             b2_1 = (((K_1^2)*cal1)-K_1+cal1)/d_1;
%             c0_1 = 1.0;
%             d0_1 = 0.0;
            %datos para filtro IIR
            %prototipo analogico
            H(s) = 1 / (s^2+sqrt(2)*s+1);
            %datos para filtro FIR
            if strcmp(value,'FIR')
                %progra filtro FIR
                Dstop1= 0.001;% primer stopband atenuation
                Dpass=0.057501127785;%passband ripple
                Dstop2=0.0001;%segundo stopband atenuation
                flag = 'scale';
                %calculo de parametros de orden usando kaiserord
                [N,Wn,BETA,TYPE] = kaiserord([Fstop1fir Fpass1fir Fpass2fir Fstop2fir]/(Fs/2), [0 1 0], [Dstop1 Dpass Dstop2]);
                %ver progra defensiva ya que si es 0 ambas frecuencias no
                %funciona, la frecuencia de corte a debe de ser menor a la
                %b porque si no no funciona.
                bfir= fir1(N,Wn,TYPE,kaiser(N+1,BETA), flag);
                Hd= dfilt.dffir(bfir);
                app.salfir_x = zeros(1,len);
                app.salfir_x= filter(bfir,1,app.editado_can1);
                app.sal_x= app.salfir_x;
                disp("Filtro FIR aplicado")
                [h1fir, w1fir] = freqz(bfir, 1);
                f_compfirch1 = freqs(bfir, 1, w1fir);
                phasefirch1 = angle(f_compfirch1);
                phasedeg_firch1 = phasefirch1*180/pi;
            elseif strcmp(value,'IIR') 
                %Progra de filtro IIR
                Fl = fb;
                Fu = fa;
                % Frecuencias de corte digitales requeridas
                wl = (2*pi*Fl)*Ts;
                wu = (2*pi*Fu)*Ts;
                % Encontramos las frecuencias analógicas deseadas Wl y Wu mediante
                % frequency pre-warping
                Wl = (2/Ts)*tan((wl)/2);
                Wu = (2/Ts)*tan((wu)/2);
                % Generamos el filtro deseado a partir del prototipo
                Hbpf_a(s) = H(1*((s^2+Wl*Wu)/((Wu-Wl)*s)));
                % Generamos el filtro digital mediante la transformación bilineal
                Hbpf(z) = Hbpf_a((2/Ts)*((z-1)/(z+1)));
                % Obtenemos el numerador y denominador
                [N, D] = numden(simplifyFraction(Hbpf));
                % Obtenemos los coeficientes del numerador y denominador
                %b = double(fliplr(coeffs(N)));
                %a = double(fliplr(coeffs(D)));
                a = fliplr(double(coeffs(D)));
                b = fliplr(double(coeffs(N)));
                % Normalizamos
                b = b / a(1);
                a = a / a(1);
                app.saliir_x = filter(b,a,app.editado_can1);
%                 y1=zeros(1,len);
%                 for n=1:(len-1)
%                     if n == 1
%                         y1(n,:)= d0_1*can1(n)+c0_1*(a0_1*can1(n));
%                     elseif n == 2
%                         y1(n,:)= d0_1*can1(n)+c0_1*((a0_1*can1(n))+(a1_1*can1(n-1))-(b1_1*y1(n-1)));
%                     else
%                         y1(n,:)= d0_1*can1(n)+c0_1*((a0_1*can1(n))+(a1_1*can1(n-1))+(a2_1*can1(n-2))-(b1_1*y1(n-1))-(b2_1*y1(n-2)));
%                     end
%                 end
%                 app.saliir_x=y1;
                app.sal_x = app.saliir_x;
                disp("Filtro IIR aplicado")
                [h1,w1] = freqz(b,a);
                f_compiirch1 = freqs(b,a,w1);
                phaseiirch1 = angle(f_compiirch1);
                phasedeg_iirch1 = phaseiirch1*180/pi;
                
            else
                app.sal_x=x;
                disp("Ningún Filtro aplicado")
            end
        end

        % Button pushed function: AplicarparmetrosButton_2
        function aplicar_param_ch2(app, event)
            
            global Hd2;
            global fa2;
            global fb2;
            global Ts;
            global len;
            global a2;
            global b2;
            global h2;
            global w2;
            global h2fir;
            global w2fir;
            global phasedeg_iirch2;
            global phasedeg_firch2;
            global x;
            global Fs;
            global gainc2;
            gainc2=app.GainSlider.Value;
            can2=x(:,2);
            cal2=app.FactordecalidadEditField_2.Value;
            if cal2<=0
                cal2=0.2;
                app.FactordecalidadEditField_2.Value=0.2;
            else
                %no hacer nada
            end
            app.editado_can2 = 5*cal2*can2*gainc2;
            dropselc2=app.TipodefiltrodelCanal2DropDown.Value;
            
            syms s z
            fb2=app.FrecuenciadecortebajaEditField_2.Value;
            fa2=app.FrecuenciadecortealtaEditField_2.Value;
            Ts = 1/Fs;
%             K_2 = tan((pi*fb2)/Fs);
%             d_2 = ((K_2^2)*cal2)+K_2+cal2;
%             a0_2 = K_2/d_2;
%             a1_2 = 0;
%             a2_2 = -K_2/d_2;
%             b1_2 = (2*cal2*((K_2^2)-1))/d_2;
%             b2_2 = (((K_2^2)*cal2)-K_2+cal2)/d_2;
%             c0_2 = 1.0;
%             d0_2 = 0.0;
            %prototipo de la funcion para filtro IIR
            H(s) = 1 / (s^2+sqrt(2)*s+1);
            
            if strcmp(dropselc2,'FIR')
                %progra filtro FIR         
                Fpass1=fb2;%frecuencia de corte baja
                Fpass2=fa2; %frecuencia de corte alta
                if fb2>=100
                    Fstop1=abs(fb2-100); %primer frecuencia de paro
                    Fstop2=abs(fa2+100);% segunda frecuencia de paro
                elseif (fb2<=0 && fa2>0) 
                    Fstop1=0;
                    Fpass1=0.01;
                    Fpass2=fa2;
                    Fstop2=fa2+100;
                elseif (fa2<=0 && fb2>0);
                    Fstop1=0;
                    Fpass1=fb2;
                    Fpass2=fb2+1;
                    Fstop2=Fpass2+100;
                elseif (fa2==0 && fb2 ==0);
                    Fstop1=0;
                    Fpass1=100;
                    Fpass2=13300;
                    Fstop2=14300;
                else
                    Fstop1=fb2;
                    Fpass1=fb2+10;
                    Fpass2=fa2;
                    Fstop2=fa2+100;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                Dstop1= 0.001;% primer stopband atenuation
                Dpass=0.057501127785;%passband ripple
                Dstop2=0.0001;%segundo stopband atenuation
                flag = 'scale';
                %calculo de parametros de orden usando kaiserord
                [N,Wn,BETA,TYPE]=kaiserord([Fstop1 Fpass1 Fpass2 Fstop2]/(Fs/2), [0 1 0], [Dstop1 Dpass Dstop2]);
                %ver progra defensiva ya que si es 0 ambas frecuencias no
                %funciona, la frecuencia de corte a debe de ser menor a la
                %b porque si no no funciona.
                bfir2= fir1(N,Wn,TYPE,kaiser(N+1,BETA), flag);
                Hd2= dfilt.dffir(bfir2);
                app.salfir2_x = zeros(1,len);
                app.salfir2_x= filter(bfir2,1,app.editado_can2);
                app.sal_x2=app.salfir2_x;
                disp("Filtro FIR aplicado - Canal 2")
                [h2fir, w2fir] = freqz(bfir2, 1);
                f_compfirch2 = freqs(bfir2, 1, w2fir);
                phasefirch2 = angle(f_compfirch2);
                phasedeg_firch2 = phasefirch2*180/pi;
            elseif strcmp(dropselc2,'IIR')
                %Progra de filtro IIR
                Fl = fb2;
                Fu = fa2;
                % Frecuencias de corte digitales requeridas
                wl = (2*pi*Fl)*Ts;
                wu = (2*pi*Fu)*Ts;
                % Encontramos las frecuencias analógicas deseadas Wl y Wu mediante
                % frequency pre-warping
                Wl = (2/Ts)*tan((wl)/2);
                Wu = (2/Ts)*tan((wu)/2);
                % Generamos el filtro deseado a partir del prototipo
                Hbpf_a(s) = H(1*((s^2+Wl*Wu)/((Wu-Wl)*s)));
                % Generamos el filtro digital mediante la transformación bilineal
                Hbpf(z) = Hbpf_a((2/Ts)*((z-1)/(z+1)));
                % Obtenemos el numerador y denominador
                [N, D] = numden(simplifyFraction(Hbpf));
                % Obtenemos los coeficientes del numerador y denominador
                %b = double(fliplr(coeffs(N)));
                %a = double(fliplr(coeffs(D)));
                a2 = fliplr(double(coeffs(D)));
                b2 = fliplr(double(coeffs(N)));
                % Normalizamos
                b2 = b2 / a2(1);
                a2 = a2 / a2(1);
                app.saliir_x=filter(b2,a2,app.editado_can2);
%                 y1=zeros(1,len);
%                 for n=1:(len-1)
%                     if n == 1
%                         y1(n,:)= d0_1*can1(n)+c0_1*(a0_1*can1(n));
%                     elseif n == 2
%                         y1(n,:)= d0_1*can1(n)+c0_1*((a0_1*can1(n))+(a1_1*can1(n-1))-(b1_1*y1(n-1)));
%                     else
%                         y1(n,:)= d0_1*can1(n)+c0_1*((a0_1*can1(n))+(a1_1*can1(n-1))+(a2_1*can1(n-2))-(b1_1*y1(n-1))-(b2_1*y1(n-2)));
%                     end
%                 end
%                 app.saliir_x=y1;
                app.sal_x2=app.saliir_x;
                disp("Filtro IIR aplicado")
                [h2,w2] = freqz(b2,a2);
                f_compiirch2 = freqs(b2,a2,w2);
                phaseiirch2 = angle(f_compiirch2);
                phasedeg_iirch2 = phaseiirch2*180/pi;
            else
                app.sal_x2=x;
            end
        end

        % Button pushed function: ReproducirCanal1Button
        function ReproducirCanal1(app, event)
            global x_out_ch1;
            global Fs;
            app.player3 = audioplayer(x_out_ch1,Fs);
            play(app.player3);
        end

        % Button pushed function: ReproducirCanal2Button
        function ReproducirCanal2(app, event)
            global x_out_ch2;
            global Fs;
            app.player4 = audioplayer(x_out_ch2,Fs);
            play(app.player4);
        end

        % Button pushed function: PausaButton
        function PausaButtonPushed(app, event)
            pause(app.player2);
            pause(app.player3);
            pause(app.player4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SeleccinTab = uitab(app.TabGroup);
            app.SeleccinTab.Title = 'Selección';
            app.CargararchivoButton = uibutton(app.SeleccinTab, 'push');
            app.CargararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivo, true);
            app.CargararchivoButton.Text = 'Cargar archivo';
            app.Lamp_2 = uilamp(app.SeleccinTab);
            app.Label = uilabel(app.SeleccinTab);
            app.Label.Text = '';
            app.GainSlider = uislider(app.SeleccinTab);
            app.GainSlider.Limits = [0 1];
            app.GainSliderLabel = uilabel(app.SeleccinTab);
            app.GainSliderLabel.Text = 'Gain';
            app.AudioLabel = uilabel(app.SeleccinTab);
            app.AudioLabel.Text = 'Audio';
            app.WaveLabel = uilabel(app.SeleccinTab);
            app.WaveLabel.Text = 'Wave';
            app.ParmetrosdeconfiguracindefiltrosporcanalPanel = uipanel(app.SeleccinTab);
            app.ParmetrosdeconfiguracindefiltrosporcanalPanel.Title = 'Parámetros de configuración de filtros por canal';
            app.Parmetrosdelfiltroparacanal1Label = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.Parmetrosdelfiltroparacanal1Label.Text = 'Parámetros del filtro para canal 1';
            app.Parmetrosdelfiltroparacanal2Label = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.Parmetrosdelfiltroparacanal2Label.Text = 'Parámetros del filtro para canal 2';
            app.FactordecalidadEditFieldLabel = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.FactordecalidadEditFieldLabel.Text = 'Factor de calidad';
            app.FactordecalidadEditField = uieditfield(app.ParmetrosdeconfiguracindefiltrosporcanalPanel, 'numeric');
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.ParmetrosdeconfiguracindefiltrosporcanalPanel, 'numeric');
            app.FrecuenciadecortebajaEditField_2Label = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.FrecuenciadecortebajaEditField_2Label.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField_2 = uieditfield(app.ParmetrosdeconfiguracindefiltrosporcanalPanel, 'numeric');
            app.FactordecalidadEditField_2Label = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.FactordecalidadEditField_2Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_2 = uieditfield(app.ParmetrosdeconfiguracindefiltrosporcanalPanel, 'numeric');
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.ParmetrosdeconfiguracindefiltrosporcanalPanel, 'numeric');
            app.FrecuenciadecortealtaEditField_2Label = uilabel(app.ParmetrosdeconfiguracindefiltrosporcanalPanel);
            app.FrecuenciadecortealtaEditField_2Label.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField_2 = uieditfield(app.ParmetrosdeconfiguracindefiltrosporcanalPanel, 'numeric');
            app.ExportarPanel = uipanel(app.SeleccinTab);
            app.ExportarPanel.Title = 'Exportar';
            app.wavLabel = uilabel(app.ExportarPanel);
            app.wavLabel.Text = '.wav';
            app.NombredearchivoEditField = uieditfield(app.ExportarPanel, 'text');
            app.NombredearchivoEditFieldLabel = uilabel(app.ExportarPanel);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.Lamp = uilamp(app.ExportarPanel);
            app.ExportarButton = uibutton(app.ExportarPanel, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @Exportar, true);
            app.ExportarButton.Text = 'Exportar';
            app.SeleccindetipodefiltroPanel = uipanel(app.SeleccinTab);
            app.SeleccindetipodefiltroPanel.Title = 'Selección de tipo de filtro';
            app.SeleccindefiltroLabel = uilabel(app.SeleccindetipodefiltroPanel);
            app.SeleccindefiltroLabel.Text = 'Selección de filtro';
            app.AplicarparmetrosButton = uibutton(app.SeleccindetipodefiltroPanel, 'push');
            app.AplicarparmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @aplicar_param_ch1, true);
            app.AplicarparmetrosButton.Text = 'Aplicar parámetros';
            app.AplicarparmetrosButton_2 = uibutton(app.SeleccindetipodefiltroPanel, 'push');
            app.AplicarparmetrosButton_2.ButtonPushedFcn = createCallbackFcn(app, @aplicar_param_ch2, true);
            app.AplicarparmetrosButton_2.Text = 'Aplicar parámetros';
            app.TipodefiltrodelCanal1DropDown = uidropdown(app.SeleccindetipodefiltroPanel);
            app.TipodefiltrodelCanal1DropDown.Items = {'FIR', 'IIR', 'Sin filtro'};
            app.TipodefiltrodelCanal1DropDown.Value = 'FIR';
            app.TipodefiltrodelCanal1DropDownLabel = uilabel(app.SeleccindetipodefiltroPanel);
            app.TipodefiltrodelCanal1DropDownLabel.Text = 'Tipo de filtro del Canal 1';
            app.TipodefiltrodelCanal2DropDownLabel = uilabel(app.SeleccindetipodefiltroPanel);
            app.TipodefiltrodelCanal2DropDownLabel.Text = 'Tipo de filtro del Canal 2';
            app.TipodefiltrodelCanal2DropDown = uidropdown(app.SeleccindetipodefiltroPanel);
            app.TipodefiltrodelCanal2DropDown.Items = {'FIR', 'IIR', 'Sin filtro'};
            app.TipodefiltrodelCanal2DropDown.Value = 'FIR';
            app.ReproduccindeaudiofiltradoPanel = uipanel(app.SeleccinTab);
            app.ReproduccindeaudiofiltradoPanel.Title = 'Reproducción de audio filtrado';
            app.ReproducirCanal1Button = uibutton(app.ReproduccindeaudiofiltradoPanel, 'push');
            app.ReproducirCanal1Button.ButtonPushedFcn = createCallbackFcn(app, @ReproducirCanal1, true);
            app.ReproducirCanal1Button.Text = 'Reproducir Canal 1';
            app.ReproducirCanal2Button = uibutton(app.ReproduccindeaudiofiltradoPanel, 'push');
            app.ReproducirCanal2Button.ButtonPushedFcn = createCallbackFcn(app, @ReproducirCanal2, true);
            app.ReproducirCanal2Button.Text = 'Reproducir Canal 2';
            app.ReproduciramboscanalesButton = uibutton(app.ReproduccindeaudiofiltradoPanel, 'push');
            app.ReproduciramboscanalesButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciramboscanalesButtonPushed, true);
            app.ReproduciramboscanalesButton.Text = 'Reproducir ambos canales';
            app.PausaButton = uibutton(app.ReproduccindeaudiofiltradoPanel, 'push');
            app.PausaButton.ButtonPushedFcn = createCallbackFcn(app, @PausaButtonPushed, true);
            app.PausaButton.Text = 'Pausa';
            app.ReproduccinddeaudiooriginalPanel = uipanel(app.SeleccinTab);
            app.ReproduccinddeaudiooriginalPanel.Title = 'Reproducciónd de audio original';
            app.ReproducirButton = uibutton(app.ReproduccinddeaudiooriginalPanel, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.PausaButton_2 = uibutton(app.ReproduccinddeaudiooriginalPanel, 'push');
            app.PausaButton_2.ButtonPushedFcn = createCallbackFcn(app, @PausaButton_2Pushed, true);
            app.PausaButton_2.Text = 'Pausa';
            app.ResumirButton = uibutton(app.ReproduccinddeaudiooriginalPanel, 'push');
            app.ResumirButton.ButtonPushedFcn = createCallbackFcn(app, @ResumirButtonPushed, true);
            app.ResumirButton.Text = 'Resumir';
            app.STOPButton = uibutton(app.ReproduccinddeaudiooriginalPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.SELECCINSwitchLabel = uilabel(app.EfectosTab);
            app.SELECCINSwitchLabel.Text = 'SELECCIÓN';
            app.SELECCINSwitch = uiswitch(app.EfectosTab, 'slider');
            app.SELECCINSwitch.Items = {'Canal 1', 'Canal 2'};
            app.SELECCINSwitch.ValueChangedFcn = createCallbackFcn(app, @channel_select, true);
            app.SELECCINSwitch.Value = 'Canal 1';
            app.EfectosCanal2Panel = uipanel(app.EfectosTab);
            app.EfectosCanal2Panel.Title = 'Efectos - Canal 2';
            app.VibratoPanel_2 = uipanel(app.EfectosCanal2Panel);
            app.VibratoPanel_2.Title = 'Vibrato ';
            app.RATEHzKnob_4Label = uilabel(app.VibratoPanel_2);
            app.RATEHzKnob_4Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_4 = uiknob(app.VibratoPanel_2, 'continuous');
            app.RATEHzKnob_4.Limits = [0 30000];
            app.RATEHzKnob_4.Value = 15000;
            app.MODFREQKnob_2Label = uilabel(app.VibratoPanel_2);
            app.MODFREQKnob_2Label.Text = 'MODFREQ';
            app.MODFREQKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.MODFREQKnob_2.Limits = [0 10];
            app.MODFREQKnob_2.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.MODFREQKnob_2.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.MODFREQKnob_2.Value = 5;
            app.VibratoCheckBox_2 = uicheckbox(app.VibratoPanel_2);
            app.VibratoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @toggle_vibrato_effect_ch2, true);
            app.VibratoCheckBox_2.Text = 'Vibrato';
            app.WIDTHKnob_2Label = uilabel(app.VibratoPanel_2);
            app.WIDTHKnob_2Label.Text = 'WIDTH';
            app.WIDTHKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.WIDTHKnob_2.Limits = [0 0.01];
            app.WIDTHKnob_2.MajorTicks = [0.0001 0.002 0.004 0.006 0.008 0.01];
            app.WIDTHKnob_2.MajorTickLabels = {'0.0001', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.WIDTHKnob_2.Value = 0.005;
            app.PhaseoFlangerPanel_2 = uipanel(app.EfectosCanal2Panel);
            app.PhaseoFlangerPanel_2.Title = 'Phase o Flanger';
            app.RATEHzKnob_5Label_2 = uilabel(app.PhaseoFlangerPanel_2);
            app.RATEHzKnob_5Label_2.Text = 'RATE (Hz)';
            app.RATEHzKnob_5 = uiknob(app.PhaseoFlangerPanel_2, 'continuous');
            app.RATEHzKnob_5.Limits = [0.1 5];
            app.RATEHzKnob_5.Value = 2.5;
            app.DELAYmsKnob_4Label = uilabel(app.PhaseoFlangerPanel_2);
            app.DELAYmsKnob_4Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_4 = uiknob(app.PhaseoFlangerPanel_2, 'continuous');
            app.DELAYmsKnob_4.Limits = [0 0.01];
            app.DELAYmsKnob_4.Value = 0.005;
            app.PhaseroFlangerCheckBox_2 = uicheckbox(app.PhaseoFlangerPanel_2);
            app.PhaseroFlangerCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @toggle_phaser_effect_ch2, true);
            app.PhaseroFlangerCheckBox_2.Text = 'Phaser o Flanger';
            app.ReverberacinPanel_2 = uipanel(app.EfectosCanal2Panel);
            app.ReverberacinPanel_2.Title = 'Reverberación';
            app.ReverberacinCheckBox_2 = uicheckbox(app.ReverberacinPanel_2);
            app.ReverberacinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @toggle_reverberation_effect_ch2, true);
            app.ReverberacinCheckBox_2.Text = 'Reverberación';
            app.RATEHzKnob_6Label_2 = uilabel(app.ReverberacinPanel_2);
            app.RATEHzKnob_6Label_2.Text = 'RATE (Hz)';
            app.RATEHzKnob_6 = uiknob(app.ReverberacinPanel_2, 'continuous');
            app.RATEHzKnob_6.Limits = [0 30000];
            app.RATEHzKnob_6.Value = 15000;
            app.DELAYmsKnob_5Label_2 = uilabel(app.ReverberacinPanel_2);
            app.DELAYmsKnob_5Label_2.Text = 'DELAY (ms)';
            app.DELAYmsKnob_5 = uiknob(app.ReverberacinPanel_2, 'continuous');
            app.DELAYmsKnob_5.Limits = [0 1];
            app.DELAYmsKnob_5.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DELAYmsKnob_5.Value = 0.5;
            app.DistorsinPanel_2 = uipanel(app.EfectosCanal2Panel);
            app.DistorsinPanel_2.Title = 'Distorsión';
            app.GAINKnob_2Label = uilabel(app.DistorsinPanel_2);
            app.GAINKnob_2Label.Text = 'GAIN';
            app.GAINKnob_2 = uiknob(app.DistorsinPanel_2, 'continuous');
            app.GAINKnob_2.Limits = [0 25];
            app.GAINKnob_2.Value = 12.5;
            app.MIXKnob_2Label = uilabel(app.DistorsinPanel_2);
            app.MIXKnob_2Label.Text = 'MIX';
            app.MIXKnob_2 = uiknob(app.DistorsinPanel_2, 'continuous');
            app.MIXKnob_2.Limits = [0 1];
            app.MIXKnob_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MIXKnob_2.Value = 0.5;
            app.DistorsinCheckBox_2 = uicheckbox(app.DistorsinPanel_2);
            app.DistorsinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @toggle_distortion_effect_ch2, true);
            app.DistorsinCheckBox_2.Text = 'Distorsión';
            app.EcoPanel_2 = uipanel(app.EfectosCanal2Panel);
            app.EcoPanel_2.Title = 'Eco';
            app.ATENUACINKnob_2Label = uilabel(app.EcoPanel_2);
            app.ATENUACINKnob_2Label.Text = 'ATENUACIÓN';
            app.ATENUACINKnob_2 = uiknob(app.EcoPanel_2, 'continuous');
            app.ATENUACINKnob_2.Limits = [0 1];
            app.ATENUACINKnob_2.Value = 0.5;
            app.DELAYmsKnob_6Label_2 = uilabel(app.EcoPanel_2);
            app.DELAYmsKnob_6Label_2.Text = 'DELAY (ms)';
            app.DELAYmsKnob_6 = uiknob(app.EcoPanel_2, 'continuous');
            app.DELAYmsKnob_6.Limits = [0 1];
            app.DELAYmsKnob_6.Value = 0.5;
            app.EcoCheckBox_2 = uicheckbox(app.EcoPanel_2);
            app.EcoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @toggle_eco_effect_ch2, true);
            app.EcoCheckBox_2.Text = 'Eco';
            app.EfectosCanal1Panel = uipanel(app.EfectosTab);
            app.EfectosCanal1Panel.Title = 'Efectos - Canal 1';
            app.VibratoPanel_1 = uipanel(app.EfectosCanal1Panel);
            app.VibratoPanel_1.Title = 'Vibrato ';
            app.RATEHzKnob_7Label = uilabel(app.VibratoPanel_1);
            app.RATEHzKnob_7Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_1 = uiknob(app.VibratoPanel_1, 'continuous');
            app.RATEHzKnob_1.Limits = [0 30000];
            app.RATEHzKnob_1.Value = 15000;
            app.MODFREQKnob_3Label = uilabel(app.VibratoPanel_1);
            app.MODFREQKnob_3Label.Text = 'MODFREQ';
            app.MODFREQKnob_1 = uiknob(app.VibratoPanel_1, 'continuous');
            app.MODFREQKnob_1.Limits = [0 10];
            app.MODFREQKnob_1.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.MODFREQKnob_1.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.MODFREQKnob_1.Value = 5;
            app.VibratoCheckBox_1 = uicheckbox(app.VibratoPanel_1);
            app.VibratoCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @toggle_vibrato_effect, true);
            app.VibratoCheckBox_1.Text = 'Vibrato';
            app.WIDTHKnob_3Label = uilabel(app.VibratoPanel_1);
            app.WIDTHKnob_3Label.Text = 'WIDTH';
            app.WIDTHKnob_1 = uiknob(app.VibratoPanel_1, 'continuous');
            app.WIDTHKnob_1.Limits = [0 0.01];
            app.WIDTHKnob_1.MajorTicks = [0.0001 0.002 0.004 0.006 0.008 0.01];
            app.WIDTHKnob_1.MajorTickLabels = {'0.0001', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.WIDTHKnob_1.Value = 0.005;
            app.PhaseoFlangerPanel_1 = uipanel(app.EfectosCanal1Panel);
            app.PhaseoFlangerPanel_1.Title = 'Phase o Flanger';
            app.RATEHzKnob_8Label = uilabel(app.PhaseoFlangerPanel_1);
            app.RATEHzKnob_8Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_2 = uiknob(app.PhaseoFlangerPanel_1, 'continuous');
            app.RATEHzKnob_2.Limits = [0.1 5];
            app.RATEHzKnob_2.Value = 2.5;
            app.DELAYmsKnob_7Label = uilabel(app.PhaseoFlangerPanel_1);
            app.DELAYmsKnob_7Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_2 = uiknob(app.PhaseoFlangerPanel_1, 'continuous');
            app.DELAYmsKnob_2.Limits = [0 0.01];
            app.DELAYmsKnob_2.Value = 0.005;
            app.PhaseroFlangerCheckBox_1 = uicheckbox(app.PhaseoFlangerPanel_1);
            app.PhaseroFlangerCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @toggle_phaser_effect, true);
            app.PhaseroFlangerCheckBox_1.Text = 'Phaser o Flanger';
            app.ReverberacinPanel_1 = uipanel(app.EfectosCanal1Panel);
            app.ReverberacinPanel_1.Title = 'Reverberación';
            app.ReverberacinCheckBox_1 = uicheckbox(app.ReverberacinPanel_1);
            app.ReverberacinCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @toggle_reverberation_effect, true);
            app.ReverberacinCheckBox_1.Text = 'Reverberación';
            app.RATEHzKnob_9Label = uilabel(app.ReverberacinPanel_1);
            app.RATEHzKnob_9Label.Text = 'RATE (Hz) ';
            app.RATEHzKnob_3 = uiknob(app.ReverberacinPanel_1, 'continuous');
            app.RATEHzKnob_3.Limits = [0 30000];
            app.RATEHzKnob_3.Value = 15000;
            app.DELAYmsKnob_8Label = uilabel(app.ReverberacinPanel_1);
            app.DELAYmsKnob_8Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_1 = uiknob(app.ReverberacinPanel_1, 'continuous');
            app.DELAYmsKnob_1.Limits = [0 1];
            app.DELAYmsKnob_1.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DELAYmsKnob_1.Value = 0.5;
            app.DistorsinPanel_1 = uipanel(app.EfectosCanal1Panel);
            app.DistorsinPanel_1.Title = 'Distorsión';
            app.GAINKnob_3Label = uilabel(app.DistorsinPanel_1);
            app.GAINKnob_3Label.Text = 'GAIN';
            app.GAINKnob_1 = uiknob(app.DistorsinPanel_1, 'continuous');
            app.GAINKnob_1.Limits = [0 25];
            app.GAINKnob_1.Value = 12.5;
            app.MIXKnob_3Label = uilabel(app.DistorsinPanel_1);
            app.MIXKnob_3Label.Text = 'MIX';
            app.MIXKnob_1 = uiknob(app.DistorsinPanel_1, 'continuous');
            app.MIXKnob_1.Limits = [0 1];
            app.MIXKnob_1.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MIXKnob_1.Value = 0.5;
            app.DistorsinCheckBox_1 = uicheckbox(app.DistorsinPanel_1);
            app.DistorsinCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @toggle_distortion_effect, true);
            app.DistorsinCheckBox_1.Text = 'Distorsión';
            app.EcoPanel_1 = uipanel(app.EfectosCanal1Panel);
            app.EcoPanel_1.Title = 'Eco';
            app.ATENUACINKnob_3Label = uilabel(app.EcoPanel_1);
            app.ATENUACINKnob_3Label.Text = 'ATENUACIÓN';
            app.ATENUACINKnob_1 = uiknob(app.EcoPanel_1, 'continuous');
            app.ATENUACINKnob_1.Limits = [0 1];
            app.ATENUACINKnob_1.Value = 0.5;
            app.DELAYmsKnob_9Label = uilabel(app.EcoPanel_1);
            app.DELAYmsKnob_9Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_3 = uiknob(app.EcoPanel_1, 'continuous');
            app.DELAYmsKnob_3.Limits = [0 1];
            app.DELAYmsKnob_3.Value = 0.5;
            app.EcoCheckBox_1 = uicheckbox(app.EcoPanel_1);
            app.EcoCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @toggle_eco_effect, true);
            app.EcoCheckBox_1.Text = 'Eco';
            app.AplicarEfectosCanal2Button = uibutton(app.EfectosTab, 'push');
            app.AplicarEfectosCanal2Button.ButtonPushedFcn = createCallbackFcn(app, @aplicar_efectos_ch2, true);
            app.AplicarEfectosCanal2Button.Text = 'Aplicar Efectos Canal 2';
            app.AplicarEfectosCanal1Button = uibutton(app.EfectosTab, 'push');
            app.AplicarEfectosCanal1Button.ButtonPushedFcn = createCallbackFcn(app, @aplicar_efectos_ch1, true);
            app.AplicarEfectosCanal1Button.Text = 'Aplicar Efectos Canal 1';
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.GraficarCanal1Button = uibutton(app.GraficosTab, 'push');
            app.GraficarCanal1Button.ButtonPushedFcn = createCallbackFcn(app, @graficar_ch1, true);
            app.GraficarCanal1Button.Text = 'Graficar Canal 1';
            app.GraficarCanal2Button = uibutton(app.GraficosTab, 'push');
            app.GraficarCanal2Button.ButtonPushedFcn = createCallbackFcn(app, @graficar_ch2, true);
            app.GraficarCanal2Button.Text = 'Graficar Canal 2';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            title(app.UIAxes2, 'Espectro Unilateral de entrada procesada')
            xlabel(app.UIAxes2, 'Hz')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraficosTab);
            title(app.UIAxes3, 'Magnitud')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes1 = uiaxes(app.GraficosTab);
            title(app.UIAxes1, 'Espectro Unilateral de entrada ')
            xlabel(app.UIAxes1, 'Hz')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes4 = uiaxes(app.GraficosTab);
            title(app.UIAxes4, 'Fase')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end