classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SeleccinTab                     matlab.ui.container.Tab
        CargararchivoButton             matlab.ui.control.Button
        ReproduciraudioButton           matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        TipodefiltroDropDown            matlab.ui.control.DropDown
        TipodefiltroDropDownLabel       matlab.ui.control.Label
        FrecuenciaCentralEditFieldLabel  matlab.ui.control.Label
        FrecuenciaCentralEditField      matlab.ui.control.NumericEditField
        FactordecalidadEditFieldLabel   matlab.ui.control.Label
        FactordecalidadEditField        matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        ReproduciraudiofiltradoButton   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ExportarButton                  matlab.ui.control.Button
        SalvaraudiofiltradoButton       matlab.ui.control.Button
        NombredearchivoEditField        matlab.ui.control.EditField
        NombredearchivoEditFieldLabel   matlab.ui.control.Label
        SeleccindefiltroLabel           matlab.ui.control.Label
        ParmetrosdelfiltroLabel         matlab.ui.control.Label
        PausarButton                    matlab.ui.control.Button
        ResumirButton                   matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        TemporalButton                  matlab.ui.control.Button
        EfectosTab                      matlab.ui.container.Tab
        SELECCINSwitchLabel             matlab.ui.control.Label
        SELECCINSwitch                  matlab.ui.control.Switch
        EfectosCanal2Panel              matlab.ui.container.Panel
        VibratoPanel_4                  matlab.ui.container.Panel
        RATEHzKnob_4Label               matlab.ui.control.Label
        RATEHzKnob_4                    matlab.ui.control.Knob
        MODFREQKnob_2Label              matlab.ui.control.Label
        MODFREQKnob_2                   matlab.ui.control.Knob
        VibratoCheckBox_2               matlab.ui.control.CheckBox
        WIDTHKnob_2Label                matlab.ui.control.Label
        WIDTHKnob_2                     matlab.ui.control.Knob
        PhaseoFlangerPanel_4            matlab.ui.container.Panel
        RATEHzKnob_5Label_2             matlab.ui.control.Label
        RATEHzKnob_5                    matlab.ui.control.Knob
        DELAYmsKnob_4Label              matlab.ui.control.Label
        DELAYmsKnob_4                   matlab.ui.control.Knob
        PhaseroFlangerCheckBox_2        matlab.ui.control.CheckBox
        ReverberacinPanel_4             matlab.ui.container.Panel
        ReverberacinCheckBox_2          matlab.ui.control.CheckBox
        RATEHzKnob_6Label_2             matlab.ui.control.Label
        RATEHzKnob_6                    matlab.ui.control.Knob
        DELAYmsKnob_5Label_2            matlab.ui.control.Label
        DELAYmsKnob_5                   matlab.ui.control.Knob
        DistorsinPanel_4                matlab.ui.container.Panel
        GAINKnob_2Label                 matlab.ui.control.Label
        GAINKnob_2                      matlab.ui.control.Knob
        MIXKnob_2Label                  matlab.ui.control.Label
        MIXKnob_2                       matlab.ui.control.Knob
        DistorsinCheckBox_2             matlab.ui.control.CheckBox
        EcoPanel_4                      matlab.ui.container.Panel
        ATENUACINKnob_2Label            matlab.ui.control.Label
        ATENUACINKnob_2                 matlab.ui.control.Knob
        DELAYmsKnob_6Label_2            matlab.ui.control.Label
        DELAYmsKnob_6                   matlab.ui.control.Knob
        EcoCheckBox_4                   matlab.ui.control.CheckBox
        EfectosCanal1Panel              matlab.ui.container.Panel
        VibratoPanel_5                  matlab.ui.container.Panel
        RATEHzKnob_7Label               matlab.ui.control.Label
        RATEHzKnob_7                    matlab.ui.control.Knob
        MODFREQKnob_3Label              matlab.ui.control.Label
        MODFREQKnob_3                   matlab.ui.control.Knob
        VibratoCheckBox_3               matlab.ui.control.CheckBox
        WIDTHKnob_3Label                matlab.ui.control.Label
        WIDTHKnob_3                     matlab.ui.control.Knob
        PhaseoFlangerPanel_5            matlab.ui.container.Panel
        RATEHzKnob_8Label               matlab.ui.control.Label
        RATEHzKnob_8                    matlab.ui.control.Knob
        DELAYmsKnob_7Label              matlab.ui.control.Label
        DELAYmsKnob_7                   matlab.ui.control.Knob
        PhaseroFlangerCheckBox_3        matlab.ui.control.CheckBox
        ReverberacinPanel_5             matlab.ui.container.Panel
        ReverberacinCheckBox_3          matlab.ui.control.CheckBox
        RATEHzKnob_9Label               matlab.ui.control.Label
        RATEHzKnob_9                    matlab.ui.control.Knob
        DELAYmsKnob_8Label              matlab.ui.control.Label
        DELAYmsKnob_8                   matlab.ui.control.Knob
        DistorsinPanel_5                matlab.ui.container.Panel
        GAINKnob_3Label                 matlab.ui.control.Label
        GAINKnob_3                      matlab.ui.control.Knob
        MIXKnob_3Label                  matlab.ui.control.Label
        MIXKnob_3                       matlab.ui.control.Knob
        DistorsinCheckBox_3             matlab.ui.control.CheckBox
        EcoPanel_5                      matlab.ui.container.Panel
        ATENUACINKnob_3Label            matlab.ui.control.Label
        ATENUACINKnob_3                 matlab.ui.control.Knob
        DELAYmsKnob_9Label              matlab.ui.control.Label
        DELAYmsKnob_9                   matlab.ui.control.Knob
        EcoCheckBox_5                   matlab.ui.control.CheckBox
        AplicarEfectosButton            matlab.ui.control.Button
        GraficosTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties(Access=public)
        file2
        player
        player2
        player3
        player4
        salfir_x
        saliir_x
    end
    methods (Access = private)


        function results = func(app)
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: CargararchivoButton
        function CargarArchivo(app, event)

            global file;
            global x; 
            global x_out;
            global Fs; 
            global len;
            global canales;
      
            file = uigetfile({'*.wav;*.mp3', 'Audio Files'});   % selecciona un archivo con extensiones específicas
            
            if  file == 0
                app.Label.Text = 'Archivo no seleccionado';
                app.Lamp_2.Color = 'r';
            else
                app.Label.Text = file;
                [x, Fs] = audioread(file);  % obtiene los datos de un archivo *.wav
                [len, canales] = size(x);
                x_out = zeros(size(x));
                app.player = audioplayer(x,Fs);
                app.Lamp_2.Color = 'g';
            end
            
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged(app, event)
            value = app.TipodefiltroDropDown.Value;
            %datos generales
            syms s z
            global x;
            global Hd;
            global fa;
            global fb;
            global Fs;
            global Ts;
            global len;
            global gain;
            fa=app.FrecuenciadecortealtaEditField.Value;
            fb=app.FrecuenciadecortebajaEditField.Value;
            gain= app.GainSlider.Value;
            Ts=1/Fs;
            %datos para filtro IIR
            %prototipo analogico
            H(s) = 1 / (s^2+sqrt(2)*s+1);
            %datos para filtro FIR
            if value =='FIR'
                %progra filtro FIR         
                Fpass1fir=fb;%frecuencia de corte baja
                Fpass2fir=fa; %frecuencia de corte alta
                Fstop1fir=abs(fb-500); %primer frecuencia de paro
                Fstop2fir=abs(fa+500);% segunda frecuencia de paro
                Dstop1= 0.001;% primer stopband atenuation
                Dpass=0.057501127785;%passband ripple
                Dstop2=0.0001;%segundo stopband atenuation
                flag = 'scale';
                %calculo de parametros de orden usando kaiserord
                [N,Wn,BETA,TYPE] = kaiserord([Fstop1fir Fpass1fir Fpass2fir Fstop2fir]/(Fs/2), [0 1 0], [Dstop1 Dpass Dstop2]);
                %ver progra defensiva ya que si es 0 ambas frecuencias no
                %funciona, la frecuencia de corte a debe de ser menor a la
                %b porque si no no funciona.
                
                bfir= fir1(N,Wn,TYPE,kaiser(N+1,BETA), flag);
                Hd= dfilt.dffir(bfir);
                app.salfir_x = zeros(1,len);
                app.salfir_x= filter(bfir,1,x);  
            elseif value == 'IIR' 
                %Progra de filtro IIR
            else
                
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            global x;
            global Hd;
            t = linspace(0,1000000000);
            plot(app.UIAxes2,x);
            plot(app.UIAxes3,app.salfir_x);
            
            
        end

        % Button pushed function: ExportarButton
        function Exportar(app, event)
            
            global x_out;                               % aquí va la variable del audio modificado
            global Fs;
            
            out_filename = app.NombredearchivoEditField.Value; % guarda nombre ingresado en la campo de edición
            out_filename = strcat(out_filename, ".wav")
            [file, path] = uiputfile(out_filename); % obtiene la dirección y el nombre para guardar el archivo
            
            % en caso de que se cancele "guardar archivo"
            if file == 0   
                return;
            end
            
            fullFileName = fullfile(path, file);    % construye la ubicación del archivo
            audiowrite(fullFileName, x_out, Fs)         % guarda el archivo en un formato *.wav
            
        end

        % Value changed function: EcoCheckBox_5
        function toggle_eco_effect(app, event)
            
            global eco_ch1;
            
            value = app.EcoCheckBox_5.Value;
            
            if value
                eco_ch1 = 1;
            else
                eco_ch1 = 0;   
            end 
            
            %******************************* efecto
            
            global x;
            global x_out;
            global Fs;
            
            ch1 = x(:,1);

            delay1 = app.DELAYmsKnob_9.Value;
            delay2 = delay1 + 0.1;
            
            a1 = app.ATENUACINKnob_3.Value;
            a2 = a1 - 0.1;
            
            R1 = round(delay1*Fs);     % retardo 1
            R2 = round(delay2*Fs);     % retardo 2
            
            if value
                for n = R2+1:1:length(x)
                    x_out(n) = x(n) + a1*x(n-R1) + a2*x_out(n-R2);
                end
            else
                x_out = x;
            end
            
        end

        % Value changed function: ReverberacinCheckBox_3
        function toggle_reverberation_effect(app, event)
            
            global reverb_ch1;
            
            value = app.ReverberacinCheckBox.Value;
            
            if value
                reverb_ch1 = 1;
            else
                reverb_ch1 = 0;
            end
            
            %******************************* efecto
            
            global x;
            global x_out;
            
            Delay = app.DELAYmsKnob.Value;
            Rate = app.RATEHzKnob.Value;
            
            R1 = round(Delay*Rate);  % retardo 1
            R2 = round(2*R1);      % retardo 2
            R3 = round(0.5*R1);    % retardo 3
            
            a1 = 0.2;   a2 = 0.2;   % atenuaciones
            
            if value
                for n = R2+R3+1:1:length(x)
                    x_out(n) = x(n) + x(n-R2) + a2*x(n-R2-R3) + a1*x_out(n-R1);
                end
            else
                x_out = x;
            end
        end

        % Value changed function: VibratoCheckBox_3
        function toggle_vibrato_effect(app, event)
            
            global vibrato_ch1;

            value = app.VibratoCheckBox.Value;
            
            if value 
                vibrato_ch1 = 1;
            else
                vibrato_ch1 = 0;
            end
            
            %******************************* efecto
            
            global x;
            global x_out;
            
            if value
                Rate = app.RATEHzKnob.Value
                Width = app.WIDTHKnob.Value
                Modfreq = app.MODFREQKnob.Value
                
                Delay = Width; % delay básico en segundos (0 - 0.01 segundos)
                DelayM = round(Delay*Rate); % delay básico en # de muestras
                AnchoM = round(Width*Rate); % ancho de modulación en # de muestras
                
                if AnchoM > DelayM 
                  error('delay greater than basic delay !!!');
                  return;
                end 
                
                ModfreqM = Modfreq/Rate; % frecuencia de modulación en # de muestras
                N = length(x);        % # of samples in WAV-file
                Delay_length = 2 + DelayM + AnchoM * 2;    % length of the entire delay  
                Delayline = zeros(Delay_length,1); % memory allocation for delay
                x_out = zeros(size(x));     % memory allocation for output vector
                for n=1:(N-1)
                   M = ModfreqM;
                   MOD = sin(M*2*pi*n);
                   TAP = 1 + DelayM + AnchoM*MOD;
                   i = floor(TAP);
                   frac = TAP - i;
                   Delayline=[x(n); Delayline(1:Delay_length-1)]; 
                   x_out(n) = Delayline(i+1)*frac + Delayline(i)*(1-frac);
                end  
                
            else
                x_out = x;
            end
        end

        % Value changed function: PhaseroFlangerCheckBox_3
        function toggle_phaser_effect(app, event)
            
            global phase_ch1;
            
            value = app.PhaseroFlangerCheckBox.Value;
            
            if value
                phase_ch1 = 1;
            else
                phase_ch1 = 0;
            end
            
            %******************************* efecto
            
            global Fs;
            global x;
            global x_out;
            
            
            if value
                max_delay = app.DELAYmsKnob.Value;
                rate = app.RATEHzKnob_2.Value;
                
                osc_sin_ref = (sin(2*pi*(1:length(x))*(rate/Fs)));
                max_samp_delay = round(max_delay*Fs);
                x_out(1:max_samp_delay)=x(1:max_samp_delay);

                amp = 0.7;
                
                for n = (max_samp_delay+1):length(x)
                    sin_val = abs(osc_sin_ref(n));
                    delay = ceil(sin_val*max_samp_delay);
                    x_out(n) = (amp*x(n)) + amp*(x(n-delay));
                end
            else
                x_out = x;
            end
            
        end

        % Value changed function: DistorsinCheckBox_3
        function toggle_distortion_effect(app, event)
            
            global distortion_ch1;
            
            value = app.DistorsinCheckBox.Value;
            
            if value
                distortion_ch1 = 1;
            else
                distortion_ch1 = 0;
            end
            
            %******************************* efecto
            
            global x;
            global x_out;


            if value
                gain = app.GAINKnob.Value;
                mix = app.MIXKnob.Value;
                
                q=x*gain/max(abs(x));
                z=sign(-q).*(1-exp(sign(-q).*q));
                x_out=mix*z*max(abs(x))/max(abs(z))+(1-mix)*x;
                x_out=x_out.*max(abs(x))/max(abs(x_out));
            else
                x_out = x;
            end
            %Actualización: Implementación de efecto de Distorsión
            %En esta actualización se incluye la implementación de efecto de Distorsión. Para esto se utilizaron dos knob control para modificar la ganancia y el mix de la distorsión.
        end

        % Button pushed function: PausarButton
        function PausarButtonPushed(app, event)
            pause(app.player);%pausa la reproduccion de audio
            app.Lamp_2.Color = 'r';
        end

        % Button pushed function: ResumirButton
        function ResumirButtonPushed(app, event)
            resume(app.player);%resume la reproduccion del audio donde se quedo
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop(app.player);%da fin a la reproduccion del audio
        end

        % Button pushed function: ReproduciraudioButton
        function ReproduciraudioButtonPushed(app, event)
            play(app.player);%reproduce el audio
        end

        % Button pushed function: TemporalButton
        function play_audio_effect(app, event)
            global Fs;
            global x_out;

            soundsc(x_out, Fs);
        end

        % Value changed function: SELECCINSwitch
        function channel_select(app, event)
            value = app.SELECCINSwitch.Value;
            switch value
                case 'Canal 1'
                    app.EfectosCanal1Panel.Visible = 'on';
                    app.EfectosCanal2Panel.Visible = 'off';
                case 'Canal 2'
                    app.EfectosCanal1Panel.Visible = 'off';
                    app.EfectosCanal2Panel.Visible = 'on';
            end 
        end

        % Button pushed function: AplicarEfectosButton
        function aplicar_efectos_ch1(app, event)
            global x;   % señal de entrada
            global eco_ch1;
            global reverb_ch1;
            global vibrato_ch1;
            global phaser_ch1;
            global distortion_ch1;
            global x_out;   % señal de salida (a la que se le aplicaran efectos)
            
            global Fs;
            
            ch1 = x(:,1);
            
            if eco_ch1
                in_eco = ch1;
                delay1 = app.DELAYmsKnob_9.Value;
                delay2 = delay1 + 0.1;
                
                a1 = app.ATENUACINKnob_3.Value;
                a2 = a1 - 0.1;
                
                R1 = round(delay1*Fs);     % retardo 1
                R2 = round(delay2*Fs);     % retardo 2
                
                for n = R2+1:1:length(in_eco)
                    eco_out(n) = in_eco(n) + a1*in_eco(n-R1) + a2*eco_out(n-R2);
                end
            end
                
            if reverb_ch1
                if eco_ch1
                    in_reberv = eco_out;
                else
                    in_reberv = ch1;
                end
                
                Delay = app.DELAYmsKnob.Value;
                Rate = app.RATEHzKnob.Value;
                
                R1 = round(Delay*Rate);  % retardo 1
                R2 = round(2*R1);      % retardo 2
                R3 = round(0.5*R1);    % retardo 3
                
                a1 = 0.2;   a2 = 0.2;   % atenuaciones
                
                for n = R2+R3+1:1:length(in_reverb)
                    reverb_out(n) = in_reverb(n) + in_reverb(n-R2) + a2*in_reverb(n-R2-R3) + a1*reverb_out(n-R1);
                end
            end
            
            if vibrato_ch1
                if reverb_ch1 
                    in_vibrato = reverb_out;
                elseif eco_ch1
                    in_vibrato = eco_out;
                else 
                    in_vibrato = ch1;
                end
                
                Rate = app.RATEHzKnob.Value
                Width = app.WIDTHKnob.Value
                Modfreq = app.MODFREQKnob.Value
                
                Delay = Width; % delay básico en segundos (0 - 0.01 segundos)
                DelayM = round(Delay*Rate); % delay básico en # de muestras
                AnchoM = round(Width*Rate); % ancho de modulación en # de muestras
                
                if AnchoM > DelayM 
                  error('delay greater than basic delay !!!');
                  return;
                end 
                
                ModfreqM = Modfreq/Rate; % frecuencia de modulación en # de muestras
                N = length(x);        % # of samples in WAV-file
                Delay_length = 2 + DelayM + AnchoM * 2;    % length of the entire delay  
                Delayline = zeros(Delay_length,1); % memory allocation for delay
                vibrato_out = zeros(size(in_vibrato));     % memory allocation for output vector
                
                for n=1:(N-1)
                   M = ModfreqM;
                   MOD = sin(M*2*pi*n);
                   TAP = 1 + DelayM + AnchoM*MOD;
                   i = floor(TAP);
                   frac = TAP - i;
                   Delayline=[in_vibrato(n); Delayline(1:Delay_length-1)]; 
                   vibrato_out(n) = Delayline(i+1)*frac + Delayline(i)*(1-frac);
                end  
            end
            
            if phaser_ch1
                if vibrato_ch1
                    in_phaser = vibrato_out;
                elseif reverb_ch1 
                    in_phaser = reverb_out;
                elseif eco_ch1
                    in_phaser = eco_out;
                else
                    in_phaser = ch1;
                end
                
                max_delay = app.DELAYmsKnob.Value;
                rate = app.RATEHzKnob_2.Value;
                
                osc_sin_ref = (sin(2*pi*(1:length(in_phaser))*(rate/Fs)));
                max_samp_delay = round(max_delay*Fs);
                phaser_out(1:max_samp_delay)=x(1:max_samp_delay);
                
                amp = 0.7;
                
                for n = (max_samp_delay+1):length(in_phaser)
                    sin_val = abs(osc_sin_ref(n));
                    delay = ceil(sin_val*max_samp_delay);
                    phaser_out(n) = (amp*in_phaser(n)) + amp*(in_phaser(n-delay));
                end
                
            end
            
            if distortion_ch1
                if phaser_ch1
                    in_distortion = phaser_out;
                elseif vibrato_ch1
                    in_distortion = vibrato_out;
                elseif reverb_ch1 
                    in_distortion = reverb_out;
                elseif eco_ch1
                    in_distortion = eco_out;
                else
                    in_distortion = ch1;
                end
                
                gain = app.GAINKnob.Value;
                mix = app.MIXKnob.Value;
                
                q=in_distortion*gain/max(abs(in_distortion));
                z=sign(-q).*(1-exp(sign(-q).*q));
                distortion_out=mix*z*max(abs(distortion_in))/max(abs(z))+(1-mix)*distortion_in;
                distortion_out=distortion_out.*max(abs(distortion_in))/max(abs(distortion_out));
            end
            
            if distortion_ch1 
                x_out = distortion_out;
            elseif phaser_ch1
                x_out = phaser_out;
            elseif vibrato_ch1
                x_out = vibrato_out;
            elseif reverb_ch1 
                x_out = reverb_out;
            elseif eco_ch1
                x_out = eco_out;
            else
                x_out = ch1;
            end
               
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SeleccinTab = uitab(app.TabGroup);
            app.SeleccinTab.Title = 'Selección';
            app.CargararchivoButton = uibutton(app.SeleccinTab, 'push');
            app.CargararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivo, true);
            app.CargararchivoButton.Text = 'Cargar archivo';
            app.ReproduciraudioButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciraudioButtonPushed, true);
            app.ReproduciraudioButton.Text = 'Reproducir audio ';
            app.Lamp_2 = uilamp(app.SeleccinTab);
            app.Label = uilabel(app.SeleccinTab);
            app.Label.Text = '';
            app.TipodefiltroDropDown = uidropdown(app.SeleccinTab);
            app.TipodefiltroDropDown.Items = {'FIR', 'IIR'};
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged, true);
            app.TipodefiltroDropDown.Value = 'FIR';
            app.TipodefiltroDropDownLabel = uilabel(app.SeleccinTab);
            app.TipodefiltroDropDownLabel.Text = 'Tipo de filtro';
            app.FrecuenciaCentralEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciaCentralEditFieldLabel.Text = 'Frecuencia Central';
            app.FrecuenciaCentralEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FactordecalidadEditFieldLabel = uilabel(app.SeleccinTab);
            app.FactordecalidadEditFieldLabel.Text = 'Factor de calidad';
            app.FactordecalidadEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.ReproduciraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudiofiltradoButton.Text = 'Reproducir audio filtrado';
            app.PlotButton = uibutton(app.SeleccinTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GainSlider = uislider(app.SeleccinTab);
            app.GainSlider.Limits = [0 1];
            app.GainSliderLabel = uilabel(app.SeleccinTab);
            app.GainSliderLabel.Text = 'Gain';
            app.Lamp = uilamp(app.SeleccinTab);
            app.ExportarButton = uibutton(app.SeleccinTab, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @Exportar, true);
            app.ExportarButton.Text = 'Exportar';
            app.SalvaraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.SalvaraudiofiltradoButton.Text = 'Salvar audio filtrado';
            app.NombredearchivoEditField = uieditfield(app.SeleccinTab, 'text');
            app.NombredearchivoEditFieldLabel = uilabel(app.SeleccinTab);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.SeleccindefiltroLabel = uilabel(app.SeleccinTab);
            app.SeleccindefiltroLabel.Text = 'Selección de filtro';
            app.ParmetrosdelfiltroLabel = uilabel(app.SeleccinTab);
            app.ParmetrosdelfiltroLabel.Text = 'Parámetros del filtro';
            app.PausarButton = uibutton(app.SeleccinTab, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @PausarButtonPushed, true);
            app.PausarButton.Text = 'Pausar';
            app.ResumirButton = uibutton(app.SeleccinTab, 'push');
            app.ResumirButton.ButtonPushedFcn = createCallbackFcn(app, @ResumirButtonPushed, true);
            app.ResumirButton.Text = 'Resumir';
            app.STOPButton = uibutton(app.SeleccinTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TemporalButton = uibutton(app.SeleccinTab, 'push');
            app.TemporalButton.ButtonPushedFcn = createCallbackFcn(app, @play_audio_effect, true);
            app.TemporalButton.Text = 'Temporal Button ';
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.SELECCINSwitchLabel = uilabel(app.EfectosTab);
            app.SELECCINSwitchLabel.Text = 'SELECCIÓN';
            app.SELECCINSwitch = uiswitch(app.EfectosTab, 'slider');
            app.SELECCINSwitch.Items = {'Canal 1', 'Canal 2'};
            app.SELECCINSwitch.ValueChangedFcn = createCallbackFcn(app, @channel_select, true);
            app.SELECCINSwitch.Value = 'Canal 1';
            app.EfectosCanal2Panel = uipanel(app.EfectosTab);
            app.EfectosCanal2Panel.Title = 'Efectos - Canal 2';
            app.VibratoPanel_4 = uipanel(app.EfectosCanal2Panel);
            app.VibratoPanel_4.Title = 'Vibrato ';
            app.RATEHzKnob_4Label = uilabel(app.VibratoPanel_4);
            app.RATEHzKnob_4Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_4 = uiknob(app.VibratoPanel_4, 'continuous');
            app.RATEHzKnob_4.Limits = [0 30000];
            app.RATEHzKnob_4.Value = 1;
            app.MODFREQKnob_2Label = uilabel(app.VibratoPanel_4);
            app.MODFREQKnob_2Label.Text = 'MODFREQ';
            app.MODFREQKnob_2 = uiknob(app.VibratoPanel_4, 'continuous');
            app.MODFREQKnob_2.Limits = [0 10];
            app.MODFREQKnob_2.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.MODFREQKnob_2.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.VibratoCheckBox_2 = uicheckbox(app.VibratoPanel_4);
            app.VibratoCheckBox_2.Text = 'Vibrato';
            app.WIDTHKnob_2Label = uilabel(app.VibratoPanel_4);
            app.WIDTHKnob_2Label.Text = 'WIDTH';
            app.WIDTHKnob_2 = uiknob(app.VibratoPanel_4, 'continuous');
            app.WIDTHKnob_2.Limits = [0 0.01];
            app.WIDTHKnob_2.MajorTicks = [0.0001 0.002 0.004 0.006 0.008 0.01];
            app.WIDTHKnob_2.MajorTickLabels = {'0.0001', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.PhaseoFlangerPanel_4 = uipanel(app.EfectosCanal2Panel);
            app.PhaseoFlangerPanel_4.Title = 'Phase o Flanger';
            app.RATEHzKnob_5Label_2 = uilabel(app.PhaseoFlangerPanel_4);
            app.RATEHzKnob_5Label_2.Text = 'RATE (Hz)';
            app.RATEHzKnob_5 = uiknob(app.PhaseoFlangerPanel_4, 'continuous');
            app.RATEHzKnob_5.Limits = [0.1 5];
            app.RATEHzKnob_5.Value = 0.1;
            app.DELAYmsKnob_4Label = uilabel(app.PhaseoFlangerPanel_4);
            app.DELAYmsKnob_4Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_4 = uiknob(app.PhaseoFlangerPanel_4, 'continuous');
            app.DELAYmsKnob_4.Limits = [0 0.01];
            app.DELAYmsKnob_4.Value = 0.01;
            app.PhaseroFlangerCheckBox_2 = uicheckbox(app.PhaseoFlangerPanel_4);
            app.PhaseroFlangerCheckBox_2.Text = 'Phaser o Flanger';
            app.ReverberacinPanel_4 = uipanel(app.EfectosCanal2Panel);
            app.ReverberacinPanel_4.Title = 'Reverberación';
            app.ReverberacinCheckBox_2 = uicheckbox(app.ReverberacinPanel_4);
            app.ReverberacinCheckBox_2.Text = 'Reverberación';
            app.RATEHzKnob_6Label_2 = uilabel(app.ReverberacinPanel_4);
            app.RATEHzKnob_6Label_2.Text = 'RATE (Hz)';
            app.RATEHzKnob_6 = uiknob(app.ReverberacinPanel_4, 'continuous');
            app.RATEHzKnob_6.Limits = [0 30000];
            app.DELAYmsKnob_5Label_2 = uilabel(app.ReverberacinPanel_4);
            app.DELAYmsKnob_5Label_2.Text = 'DELAY (ms)';
            app.DELAYmsKnob_5 = uiknob(app.ReverberacinPanel_4, 'continuous');
            app.DELAYmsKnob_5.Limits = [0 1];
            app.DELAYmsKnob_5.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DistorsinPanel_4 = uipanel(app.EfectosCanal2Panel);
            app.DistorsinPanel_4.Title = 'Distorsión';
            app.GAINKnob_2Label = uilabel(app.DistorsinPanel_4);
            app.GAINKnob_2Label.Text = 'GAIN';
            app.GAINKnob_2 = uiknob(app.DistorsinPanel_4, 'continuous');
            app.GAINKnob_2.Limits = [0 25];
            app.MIXKnob_2Label = uilabel(app.DistorsinPanel_4);
            app.MIXKnob_2Label.Text = 'MIX';
            app.MIXKnob_2 = uiknob(app.DistorsinPanel_4, 'continuous');
            app.MIXKnob_2.Limits = [0 1];
            app.MIXKnob_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MIXKnob_2.Value = 0.1;
            app.DistorsinCheckBox_2 = uicheckbox(app.DistorsinPanel_4);
            app.DistorsinCheckBox_2.Text = 'Distorsión';
            app.EcoPanel_4 = uipanel(app.EfectosCanal2Panel);
            app.EcoPanel_4.Title = 'Eco';
            app.ATENUACINKnob_2Label = uilabel(app.EcoPanel_4);
            app.ATENUACINKnob_2Label.Text = 'ATENUACIÓN';
            app.ATENUACINKnob_2 = uiknob(app.EcoPanel_4, 'continuous');
            app.ATENUACINKnob_2.Limits = [0 1];
            app.ATENUACINKnob_2.Value = 0.01;
            app.DELAYmsKnob_6Label_2 = uilabel(app.EcoPanel_4);
            app.DELAYmsKnob_6Label_2.Text = 'DELAY (ms)';
            app.DELAYmsKnob_6 = uiknob(app.EcoPanel_4, 'continuous');
            app.DELAYmsKnob_6.Limits = [0 1];
            app.EcoCheckBox_4 = uicheckbox(app.EcoPanel_4);
            app.EcoCheckBox_4.Text = 'Eco';
            app.EfectosCanal1Panel = uipanel(app.EfectosTab);
            app.EfectosCanal1Panel.Title = 'Efectos - Canal 1';
            app.VibratoPanel_5 = uipanel(app.EfectosCanal1Panel);
            app.VibratoPanel_5.Title = 'Vibrato ';
            app.RATEHzKnob_7Label = uilabel(app.VibratoPanel_5);
            app.RATEHzKnob_7Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_7 = uiknob(app.VibratoPanel_5, 'continuous');
            app.RATEHzKnob_7.Limits = [0 30000];
            app.RATEHzKnob_7.Value = 1;
            app.MODFREQKnob_3Label = uilabel(app.VibratoPanel_5);
            app.MODFREQKnob_3Label.Text = 'MODFREQ';
            app.MODFREQKnob_3 = uiknob(app.VibratoPanel_5, 'continuous');
            app.MODFREQKnob_3.Limits = [0 10];
            app.MODFREQKnob_3.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.MODFREQKnob_3.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.VibratoCheckBox_3 = uicheckbox(app.VibratoPanel_5);
            app.VibratoCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @toggle_vibrato_effect, true);
            app.VibratoCheckBox_3.Text = 'Vibrato';
            app.WIDTHKnob_3Label = uilabel(app.VibratoPanel_5);
            app.WIDTHKnob_3Label.Text = 'WIDTH';
            app.WIDTHKnob_3 = uiknob(app.VibratoPanel_5, 'continuous');
            app.WIDTHKnob_3.Limits = [0 0.01];
            app.WIDTHKnob_3.MajorTicks = [0.0001 0.002 0.004 0.006 0.008 0.01];
            app.WIDTHKnob_3.MajorTickLabels = {'0.0001', '0.002', '0.004', '0.006', '0.008', '0.01'};
            app.PhaseoFlangerPanel_5 = uipanel(app.EfectosCanal1Panel);
            app.PhaseoFlangerPanel_5.Title = 'Phase o Flanger';
            app.RATEHzKnob_8Label = uilabel(app.PhaseoFlangerPanel_5);
            app.RATEHzKnob_8Label.Text = 'RATE (Hz)';
            app.RATEHzKnob_8 = uiknob(app.PhaseoFlangerPanel_5, 'continuous');
            app.RATEHzKnob_8.Limits = [0.1 5];
            app.RATEHzKnob_8.Value = 0.1;
            app.DELAYmsKnob_7Label = uilabel(app.PhaseoFlangerPanel_5);
            app.DELAYmsKnob_7Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_7 = uiknob(app.PhaseoFlangerPanel_5, 'continuous');
            app.DELAYmsKnob_7.Limits = [0 0.01];
            app.DELAYmsKnob_7.Value = 0.01;
            app.PhaseroFlangerCheckBox_3 = uicheckbox(app.PhaseoFlangerPanel_5);
            app.PhaseroFlangerCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @toggle_phaser_effect, true);
            app.PhaseroFlangerCheckBox_3.Text = 'Phaser o Flanger';
            app.ReverberacinPanel_5 = uipanel(app.EfectosCanal1Panel);
            app.ReverberacinPanel_5.Title = 'Reverberación';
            app.ReverberacinCheckBox_3 = uicheckbox(app.ReverberacinPanel_5);
            app.ReverberacinCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @toggle_reverberation_effect, true);
            app.ReverberacinCheckBox_3.Text = 'Reverberación';
            app.RATEHzKnob_9Label = uilabel(app.ReverberacinPanel_5);
            app.RATEHzKnob_9Label.Text = 'RATE (Hz) ';
            app.RATEHzKnob_9 = uiknob(app.ReverberacinPanel_5, 'continuous');
            app.RATEHzKnob_9.Limits = [0 30000];
            app.DELAYmsKnob_8Label = uilabel(app.ReverberacinPanel_5);
            app.DELAYmsKnob_8Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_8 = uiknob(app.ReverberacinPanel_5, 'continuous');
            app.DELAYmsKnob_8.Limits = [0 1];
            app.DELAYmsKnob_8.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DistorsinPanel_5 = uipanel(app.EfectosCanal1Panel);
            app.DistorsinPanel_5.Title = 'Distorsión';
            app.GAINKnob_3Label = uilabel(app.DistorsinPanel_5);
            app.GAINKnob_3Label.Text = 'GAIN';
            app.GAINKnob_3 = uiknob(app.DistorsinPanel_5, 'continuous');
            app.GAINKnob_3.Limits = [0 25];
            app.MIXKnob_3Label = uilabel(app.DistorsinPanel_5);
            app.MIXKnob_3Label.Text = 'MIX';
            app.MIXKnob_3 = uiknob(app.DistorsinPanel_5, 'continuous');
            app.MIXKnob_3.Limits = [0 1];
            app.MIXKnob_3.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MIXKnob_3.Value = 0.1;
            app.DistorsinCheckBox_3 = uicheckbox(app.DistorsinPanel_5);
            app.DistorsinCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @toggle_distortion_effect, true);
            app.DistorsinCheckBox_3.Text = 'Distorsión';
            app.EcoPanel_5 = uipanel(app.EfectosCanal1Panel);
            app.EcoPanel_5.Title = 'Eco';
            app.ATENUACINKnob_3Label = uilabel(app.EcoPanel_5);
            app.ATENUACINKnob_3Label.Text = 'ATENUACIÓN';
            app.ATENUACINKnob_3 = uiknob(app.EcoPanel_5, 'continuous');
            app.ATENUACINKnob_3.Limits = [0 1];
            app.ATENUACINKnob_3.Value = 0.01;
            app.DELAYmsKnob_9Label = uilabel(app.EcoPanel_5);
            app.DELAYmsKnob_9Label.Text = 'DELAY (ms)';
            app.DELAYmsKnob_9 = uiknob(app.EcoPanel_5, 'continuous');
            app.DELAYmsKnob_9.Limits = [0 1];
            app.EcoCheckBox_5 = uicheckbox(app.EcoPanel_5);
            app.EcoCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @toggle_eco_effect, true);
            app.EcoCheckBox_5.Text = 'Eco';
            app.AplicarEfectosButton = uibutton(app.EfectosTab, 'push');
            app.AplicarEfectosButton.ButtonPushedFcn = createCallbackFcn(app, @aplicar_efectos_ch1, true);
            app.AplicarEfectosButton.Text = 'Aplicar Efectos';
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraficosTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.GraficosTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
        end
    end

    methods (Access = public)

        % Construct app
        function app = App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end